classdef Calibration_Point_Selection_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CurrentPointCoordinatesEditField  matlab.ui.control.EditField
        CurrentPointCoordinatesEditFieldLabel  matlab.ui.control.Label
        ViewsCamSpinner          matlab.ui.control.Spinner
        ViewsCamSpinnerLabel     matlab.ui.control.Label
        ComputeDirectLinearTransformPanel  matlab.ui.container.Panel
        PlotTypeDropDown         matlab.ui.control.DropDown
        PlotTypeDropDownLabel    matlab.ui.control.Label
        ComputeDLTButton         matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        ViewsPanel               matlab.ui.container.Panel
        EnhanceDropDown          matlab.ui.control.DropDown
        EnhanceDropDownLabel     matlab.ui.control.Label
        ZoomLabeledButton        matlab.ui.control.Button
        ZoomHomeButton           matlab.ui.control.Button
        GotoEditField            matlab.ui.control.NumericEditField
        GotoEditFieldLabel       matlab.ui.control.Label
        nLabel                   matlab.ui.control.Label
        CurrentPointLabel        matlab.ui.control.Label
        GoBackButton             matlab.ui.control.Button
        GonextButton             matlab.ui.control.Button
        TypeViewDropDown         matlab.ui.control.DropDown
        TypeDropDown_2Label      matlab.ui.control.Label
        PointDetectionPanel      matlab.ui.container.Panel
        DeletePointButton        matlab.ui.control.Button
        ViewDropDown             matlab.ui.control.DropDown
        ViewDropDownLabel        matlab.ui.control.Label
        ConfirmPointButton       matlab.ui.control.Button
        ManualSelectButton       matlab.ui.control.Button
        BufferEditField          matlab.ui.control.NumericEditField
        BufferLabel              matlab.ui.control.Label
        targetpxlEditField       matlab.ui.control.NumericEditField
        targetpxlEditFieldLabel  matlab.ui.control.Label
        TypeDetectDropDown       matlab.ui.control.DropDown
        TypeDropDownLabel        matlab.ui.control.Label
        AutodetectButton         matlab.ui.control.Button
        CameraDropDown           matlab.ui.control.DropDown
        CameraDropDownLabel      matlab.ui.control.Label
        SaveDataButton           matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~evalin( 'base', 'exist("frame","var") == 1' )
                assignin('base','frame',{});
                assignin('base','nVideo',0);
            end
            if ~evalin( 'base', 'exist("background","var") == 1' )
                assignin('base','stimulus',{});
            end
            if ~evalin( 'base', 'exist("points","var") == 1' )
                assignin('base','points',[]);
                assignin('base','n',nan);
            end
            if ~evalin( 'base', 'exist("filepath","var") == 1' )
                assignin('base','filepath',[]);
            end
            assignin('base','currCentroid',[nan nan]);
            %assignin('base','finalCentroid',[]);

            opts.buffer = app.BufferEditField.Value;
            opts.targetPixels = app.targetpxlEditField.Value;
            assignin('base','opts',opts);

            addpath(genpath([pwd '/Subroutines']))
            addpath(genpath([pwd '/Utils']))

        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
             filepath = evalin('base','filepath');
            [filename,filepath] = uigetfile({[filepath '*.mat']}, 'Select Calibration File');
        
            load([filepath filename], 'frame', 'background', 'points', 'finalCentroid');
        
            % =========================================================
            % 🔥 FORCE CONSISTENT GRAYSCALE FORMAT (IMPORTANT FIX)
            % =========================================================
            for i = 1:numel(frame)
        
                % --- Fix frame stack ---
                for n = 1:size(frame{i},3)
                    img = frame{i}(:,:,n);
        
                    if size(img,3) == 3
                        img = rgb2gray(img);
                    end
        
                    frame{i}(:,:,n) = img;
                end
        
                % --- Fix background ---
                bg = background{i};
        
                if size(bg,3) == 3
                    bg = rgb2gray(bg);
                end
        
                background{i} = bg;
        
            end
        
            % =========================================================
        
            assignin('base','frame',frame);
            assignin('base','background',background);
            assignin('base','points',points);
            assignin('base','filepath',filepath);
            assignin('base','n',numel(points.XX));
            assignin('base','nVideo',numel(frame));
            assignin('base','currFrame',1);
        
            assignin('base','rmse_xyz',[]);
            assignin('base','recon_xyz',[]);
            assignin('base','reconstructableFrames',[]);
            assignin('base','c',[]);
        
            app.CurrentPointLabel.Text = sprintf('Current Frame: %d/%d', 1, numel(points.XX));
        
            opts = evalin('base','opts');
            GUIax = app.UIAxes;
            assignin('base','ax',GUIax);
        
            app.CameraDropDown.Items = strsplit(num2str(1:numel(frame)));
            app.CameraDropDown.Value = '1';
        
            for cameraNum = 1:numel(frame)
                [framesBS{cameraNum}, maxFrameMeanBS{cameraNum}, opts] = ...
                    getBaseline(background, frame, cameraNum, opts);
            end
        
            opts.imageEnhance = app.EnhanceDropDown.Value;
        
            assignin('base','framesBS',framesBS);
            assignin('base','maxFrameMeanBS',maxFrameMeanBS);
            assignin('base','opts',opts);
        
            % Ensure centroid structure exists
            if exist('finalCentroid','var') ~= 1
                finalCentroid = cell(size(frame));
                for i = 1:numel(frame)
                    finalCentroid{i} = nan(numel(points.XX),2);
                end
            end
        
            assignin('base','finalCentroid',finalCentroid);
        
            % plotting
            plotCalibrationImage(app.UIAxes, app.TypeViewDropDown.Value, 1, 1, 1, opts);
        end

        % Value changed function: TypeViewDropDown
        function TypeViewDropDownValueChanged(app, event)
            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
            
        end

        % Value changed function: GotoEditField
        function GotoEditFieldValueChanged(app, event)
            n = evalin('base','n');
            points = evalin('base', 'points');
            currFrame = app.GotoEditField.Value;
            currFrame = min(max(currFrame,1),n);
            assignin('base','currFrame',currFrame);
            app.CurrentPointLabel.Text = sprintf('Current Frame: %d/%d', currFrame, n);
            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(currFrame),points.YY(currFrame),points.ZZ(currFrame));
            assignin('base','currCentroid',[nan nan]);

            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts)
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            n = evalin('base','n');
            points = evalin('base', 'points');
            currFrame = max(evalin('base','currFrame')-1,1);
            assignin('base','currFrame',currFrame);
            app.CurrentPointLabel.Text = sprintf('Current Frame: %d/%d', currFrame, n);
            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(currFrame),points.YY(currFrame),points.ZZ(currFrame));
            assignin('base','currCentroid',[nan nan]);

            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts)
        end

        % Button pushed function: GonextButton
        function GonextButtonPushed(app, event)
            n = evalin('base','n');
            points = evalin('base', 'points');
            currFrame = min(evalin('base','currFrame')+1,n);
            assignin('base','currFrame',currFrame);
            app.CurrentPointLabel.Text = sprintf('Current Frame: %d/%d', currFrame, n);
            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(currFrame),points.YY(currFrame),points.ZZ(currFrame));
            assignin('base','currCentroid',[nan nan]);

            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts)
        end

        % Button pushed function: AutodetectButton
        function AutodetectButtonPushed(app, event)
            framesBS = evalin('base','framesBS');
            maxFrameMeanBS = evalin('base','maxFrameMeanBS');
            opts = evalin('base','opts');
            currFrame = evalin('base','currFrame');
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);

            [targetCentroid,targetLabel] = detectTarget(...
                framesBS{cameraNum}(:,:,currFrame),...
                maxFrameMeanBS{cameraNum}(currFrame),cameraNum,opts);
            switch app.TypeDetectDropDown.Value
                case 'Circularity'
                    currCentroid = targetCentroid(1,:);
                case 'Area'
                    currCentroid = targetCentroid(2,:);
                case 'Both'
                    currCentroid = targetCentroid(3,:);
            end
            assignin('base','currCentroid',currCentroid);

            % plotting
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts,true)
            
        end

        % Button pushed function: ConfirmPointButton
        function ConfirmPointButtonPushed(app, event)
            finalCentroid = evalin('base','finalCentroid');
            currCentroid = evalin('base','currCentroid');
            currFrame = evalin('base','currFrame');
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);

            finalCentroid{cameraNum,currView}(currFrame,:) = currCentroid;
            assignin('base','finalCentroid',finalCentroid);

            % plotting
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
        end

        % Button pushed function: ManualSelectButton
        function ManualSelectButtonPushed(app, event)
            tmpROI = drawpoint(app.UIAxes,'Visible','off');
            currCentroid = tmpROI.Position;
            assignin('base','currCentroid',currCentroid);

            % plotting
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
        end

        % Button pushed function: ZoomHomeButton
        function ZoomHomeButtonPushed(app, event)
            % plotting
            frame = evalin('base','frame');
            cameraNum = str2double(app.CameraDropDown.Value);
            currFrame = evalin('base','currFrame');
            I = frame{cameraNum}(:,:,currFrame);
            app.UIAxes.XLim = [0 size(I,2)];
            app.UIAxes.YLim = [0 size(I,1)];
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            frame = evalin('base','frame');
            background = evalin('base','background');
            points = evalin('base','points');
            finalCentroid = evalin('base','finalCentroid');

            rmse_xyz = evalin('base','rmse_xyz');
            recon_xyz = evalin('base','recon_xyz');
            reconstructableFrames = evalin('base','reconstructableFrames');
            c = evalin('base','c');

            filepath = evalin('base','filepath');
            [filename,filepath] = uiputfile([filepath 'DLT_Calibration.mat'],'Name Save File');
            assignin('base','filepath',filepath);
            save([filepath filename],'frame','background','points','finalCentroid','rmse_xyz','recon_xyz','reconstructableFrames','c');
        end

        % Button pushed function: ZoomLabeledButton
        function ZoomLabeledButtonPushed(app, event)
            % plotting
            currCentroid = evalin('base','currCentroid');
            finalCentroid = evalin('base','finalCentroid');
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);
            currFrame = evalin('base','currFrame');
            if ~isnan(finalCentroid{cameraNum,currView}(currFrame,1))
                app.UIAxes.XLim = finalCentroid{cameraNum,currView}(currFrame,1)+[-50 50];
                app.UIAxes.YLim = finalCentroid{cameraNum,currView}(currFrame,2)+[-50 50];
            elseif ~all(isnan(currCentroid))
                app.UIAxes.XLim = currCentroid(1)+[-50 50];
                app.UIAxes.YLim = currCentroid(2)+[-50 50];
            end
            
        end

        % Value changed function: targetpxlEditField
        function targetpxlEditFieldValueChanged(app, event)
            opts = evalin('base','opts');
            opts.targetPixels = app.targetpxlEditField.Value;
            assignin('base','opts',opts);
        end

        % Value changed function: BufferEditField
        function BufferEditFieldValueChanged(app, event)
            opts = evalin('base','opts');
            opts.buffer = app.BufferEditField.Value;
            assignin('base','opts',opts);
        end

        % Button pushed function: ComputeDLTButton
        function ComputeDLTButtonPushed(app, event)
            finalCentroid = evalin('base','finalCentroid');
            points = evalin('base','points');
            nCamera = size(finalCentroid,1);
            nView = size(finalCentroid,2);
            frame = [points.XX,points.YY,points.ZZ];
            n = 1;
            for i = 1:nCamera
                for j = 1:nView
                    camPts = finalCentroid{i,j};
                    [c(:,n),rmse(:,n)] = dlt_computeCoefficients(frame,camPts);
                    finalCentroid_lin{n} = camPts;
                    n = n+1;
                end
            end
            c(:,isnan(rmse)) = [];
            camPts = cell2mat(finalCentroid_lin(~isnan(rmse)));
            %camPts = cell2mat(reshape(finalCentroid,1,nCamera));

%             % Give the estimate of the calibration and reconstruction errors
%             numTrials = 200;
%             disp('Testing calibration...')
%             [calibrationError, calibModelError1] = ...
%                 test_calibration(frame, camPts, numTrials);
%             disp('Testing reconstruction...')
%             [calibModelError, reconModelError] = ...
%                 test_reconstruction(frame, camPts, numTrials);

            
            [recon_xyz,rmse_uv] = dlt_reconstruct(c,camPts);
            reconstructableFrames = ~isnan(recon_xyz(:,1));

            % Calculate the root mean square error between the predicted and measured points
            sq_err = (recon_xyz(reconstructableFrames,:)-frame(reconstructableFrames,:)).^2;
            rmse_xyz = sqrt(mean(sq_err,2));
            rmse_uv = rmse_uv(reconstructableFrames);


            nCameraUsed = size(camPts,2)./2-sum(isnan(camPts),2)./2;
            nCameraUsed(~reconstructableFrames) = [];
            bins_uv = 0:0.25:ceil(max(rmse_uv));
            bins_xyz = linspace(floor(min(rmse_xyz)),ceil(max(rmse_xyz)),11);
            for n = 2:max(nCameraUsed)
                y_uv(n-1,:) = histcounts(rmse_uv(nCameraUsed==n),bins_uv,'Normalization','probability');
                y_xyz(n-1,:) = histcounts(rmse_xyz(nCameraUsed==n),bins_xyz,'Normalization','probability');
            end

            switch app.PlotTypeDropDown.Value
                case 'Data'
                    scatter3(app.UIAxes_3,recon_xyz(:,1),recon_xyz(:,2),recon_xyz(:,3),'bo');
                    hold(app.UIAxes_3,'on')
                    scatter3(app.UIAxes_3,frame(reconstructableFrames,1),...
                        frame(reconstructableFrames,2),frame(reconstructableFrames,3),'r*');
                    hold(app.UIAxes_3,'off')
                case 'Error'
                    CMap=colormap(hot(100));
                    cc = CMap(round(rmse_xyz./ceil(max(rmse_xyz)).*100),:);
                    scatter3(app.UIAxes_3,frame(reconstructableFrames,1),...
                        frame(reconstructableFrames,2),frame(reconstructableFrames,3),36,cc)
            end


            plot(app.UIAxes_2,(bins_uv(2:end)+bins_uv(1:end-1))./2,y_uv');
            legend(app.UIAxes_2,strsplit(num2str(2:max(nCameraUsed))))
            xlabel(app.UIAxes_2,'error (pixels)')
            plot(app.UIAxes_4,(bins_xyz(2:end)+bins_xyz(1:end-1))./2,y_xyz');
            xlabel(app.UIAxes_4,'error (um)')

%             histogram(app.UIAxes_2,rmse_uv);
%             xlabel(app.UIAxes_2,'error (pixels)')
%             histogram(app.UIAxes_4,rmse_xyz);
%             xlabel(app.UIAxes_4,'error (um)')

            assignin('base','rmse_xyz',rmse_xyz);
            assignin('base','recon_xyz',recon_xyz);
            assignin('base','reconstructableFrames',reconstructableFrames);
            assignin('base','c',c);
            
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            points = evalin('base','points');
            frame = [points.XX,points.YY,points.ZZ];
            rmse_xyz = evalin('base','rmse_xyz');
            recon_xyz = evalin('base','recon_xyz');
            reconstructableFrames = evalin('base','reconstructableFrames');

            switch app.PlotTypeDropDown.Value
                case 'Data'
                    scatter3(app.UIAxes_3,recon_xyz(:,1),recon_xyz(:,2),recon_xyz(:,3),'bo');
                    hold(app.UIAxes_3,'on')
                    scatter3(app.UIAxes_3,frame(reconstructableFrames,1),...
                        frame(reconstructableFrames,2),frame(reconstructableFrames,3),'r*');
                    hold(app.UIAxes_3,'off')
                case 'Error'
                    CMap=colormap(hot(100));
                    cc = CMap(round(rmse_xyz./ceil(max(rmse_xyz)).*100),:);
                    scatter3(app.UIAxes_3,frame(reconstructableFrames,1),...
                        frame(reconstructableFrames,2),frame(reconstructableFrames,3),36,cc)
            end
        end

        % Value changed function: ViewsCamSpinner
        function ViewsCamSpinnerValueChanged(app, event)
            nView_perCamera = app.ViewsCamSpinner.Value;
            app.ViewDropDown.Items = cellstr(num2str([1:nView_perCamera]'))';

            finalCentroid = evalin('base','finalCentroid');
            points = evalin('base','points');
            if size(finalCentroid,2)<nView_perCamera
                for i = (size(finalCentroid,2)+1):nView_perCamera
                    finalCentroid(:,i) = {nan(numel(points.XX),2)};
                end
            end
            assignin('base','finalCentroid',finalCentroid);
            %finalCentroid = evalin('base','finalCentroid');

        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            % plotting
            currView = str2double(app.ViewDropDown.Value);
            cameraNum = str2double(app.CameraDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
        end

        % Value changed function: EnhanceDropDown
        function EnhanceDropDownValueChanged(app, event)
            
            currView = str2double(app.ViewDropDown.Value);
            cameraNum = str2double(app.CameraDropDown.Value);
            currFrame = evalin('base','currFrame');
            opts = evalin('base','opts');
            opts.imageEnhance = app.EnhanceDropDown.Value;
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
            assignin('base','opts',opts);
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            finalCentroid = evalin('base','finalCentroid');
            currFrame = evalin('base','currFrame');
            cameraNum = str2double(app.CameraDropDown.Value);
            currView = str2double(app.ViewDropDown.Value);

            finalCentroid{cameraNum,currView}(currFrame,:) = nan;
            assignin('base','finalCentroid',finalCentroid);

            % plotting
            opts = evalin('base','opts');
            plotCalibrationImage(app.UIAxes,app.TypeViewDropDown.Value,...
                cameraNum,currView,currFrame,opts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabelRotationMode = 'manual';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabelRotationMode = 'manual';
            app.UIAxes.ZTickLabelRotationMode = 'manual';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Backprop UV error (pxl)')
            app.UIAxes_2.XTickLabelRotationMode = 'manual';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabelRotationMode = 'manual';
            app.UIAxes_2.ZTickLabelRotationMode = 'manual';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'XYZ error (um)')
            app.UIAxes_4.XTickLabelRotationMode = 'manual';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabelRotationMode = 'manual';
            app.UIAxes_4.ZTickLabelRotationMode = 'manual';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Reconstructed')
            xlabel(app.UIAxes_3, 'X (um)')
            ylabel(app.UIAxes_3, 'Y (um)')
            zlabel(app.UIAxes_3, 'Z (um)')
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.CameraDropDownLabel = uilabel(app.UIFigure);
            app.CameraDropDownLabel.Text = 'Camera #';
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.Items = {'1'};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = '1';
            app.PointDetectionPanel = uipanel(app.UIFigure);
            app.PointDetectionPanel.Title = 'Point Detection';
            app.AutodetectButton = uibutton(app.PointDetectionPanel, 'push');
            app.AutodetectButton.ButtonPushedFcn = createCallbackFcn(app, @AutodetectButtonPushed, true);
            app.AutodetectButton.Text = 'Auto-detect';
            app.TypeDropDownLabel = uilabel(app.PointDetectionPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDetectDropDown = uidropdown(app.PointDetectionPanel);
            app.TypeDetectDropDown.Items = {'Circularity', 'Area', 'Both'};
            app.TypeDetectDropDown.Value = 'Circularity';
            app.targetpxlEditFieldLabel = uilabel(app.PointDetectionPanel);
            app.targetpxlEditFieldLabel.Text = 'target pxl #';
            app.targetpxlEditField = uieditfield(app.PointDetectionPanel, 'numeric');
            app.targetpxlEditField.Limits = [0 Inf];
            app.targetpxlEditField.ValueChangedFcn = createCallbackFcn(app, @targetpxlEditFieldValueChanged, true);
            app.targetpxlEditField.Value = 5000;
            app.BufferLabel = uilabel(app.PointDetectionPanel);
            app.BufferLabel.Text = 'Buffer';
            app.BufferEditField = uieditfield(app.PointDetectionPanel, 'numeric');
            app.BufferEditField.ValueChangedFcn = createCallbackFcn(app, @BufferEditFieldValueChanged, true);
            app.BufferEditField.Value = 5;
            app.ManualSelectButton = uibutton(app.PointDetectionPanel, 'push');
            app.ManualSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSelectButtonPushed, true);
            app.ManualSelectButton.Text = 'Manual Select';
            app.ConfirmPointButton = uibutton(app.PointDetectionPanel, 'push');
            app.ConfirmPointButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPointButtonPushed, true);
            app.ConfirmPointButton.Text = 'Confirm Point';
            app.ViewDropDownLabel = uilabel(app.PointDetectionPanel);
            app.ViewDropDownLabel.Text = 'View #';
            app.ViewDropDown = uidropdown(app.PointDetectionPanel);
            app.ViewDropDown.Items = {'1'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = '1';
            app.DeletePointButton = uibutton(app.PointDetectionPanel, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.ViewsPanel = uipanel(app.UIFigure);
            app.ViewsPanel.Title = 'Views';
            app.TypeDropDown_2Label = uilabel(app.ViewsPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeViewDropDown = uidropdown(app.ViewsPanel);
            app.TypeViewDropDown.Items = {'Raw', 'Baseline Sub', 'Binarized'};
            app.TypeViewDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeViewDropDownValueChanged, true);
            app.TypeViewDropDown.Value = 'Baseline Sub';
            app.GonextButton = uibutton(app.ViewsPanel, 'push');
            app.GonextButton.ButtonPushedFcn = createCallbackFcn(app, @GonextButtonPushed, true);
            app.GonextButton.Text = 'Go next';
            app.GoBackButton = uibutton(app.ViewsPanel, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.CurrentPointLabel = uilabel(app.ViewsPanel);
            app.CurrentPointLabel.Text = 'Current Frame: 1/n';
            app.nLabel = uilabel(app.ViewsPanel);
            app.nLabel.Text = '/n';
            app.GotoEditFieldLabel = uilabel(app.ViewsPanel);
            app.GotoEditFieldLabel.Text = 'Go to';
            app.GotoEditField = uieditfield(app.ViewsPanel, 'numeric');
            app.GotoEditField.ValueChangedFcn = createCallbackFcn(app, @GotoEditFieldValueChanged, true);
            app.ZoomHomeButton = uibutton(app.ViewsPanel, 'push');
            app.ZoomHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomHomeButtonPushed, true);
            app.ZoomHomeButton.Text = 'Zoom Home';
            app.ZoomLabeledButton = uibutton(app.ViewsPanel, 'push');
            app.ZoomLabeledButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomLabeledButtonPushed, true);
            app.ZoomLabeledButton.Text = 'Zoom Labeled';
            app.EnhanceDropDownLabel = uilabel(app.ViewsPanel);
            app.EnhanceDropDownLabel.Text = 'Enhance';
            app.EnhanceDropDown = uidropdown(app.ViewsPanel);
            app.EnhanceDropDown.Items = {'None', 'Brighten', 'histeq', 'adapthisteq'};
            app.EnhanceDropDown.ValueChangedFcn = createCallbackFcn(app, @EnhanceDropDownValueChanged, true);
            app.EnhanceDropDown.Value = 'None';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ComputeDirectLinearTransformPanel = uipanel(app.UIFigure);
            app.ComputeDirectLinearTransformPanel.Title = 'Compute Direct Linear Transform';
            app.ComputeDLTButton = uibutton(app.ComputeDirectLinearTransformPanel, 'push');
            app.ComputeDLTButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDLTButtonPushed, true);
            app.ComputeDLTButton.Text = 'Compute DLT';
            app.PlotTypeDropDownLabel = uilabel(app.ComputeDirectLinearTransformPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.ComputeDirectLinearTransformPanel);
            app.PlotTypeDropDown.Items = {'Data', 'Error'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Data';
            app.ViewsCamSpinnerLabel = uilabel(app.UIFigure);
            app.ViewsCamSpinnerLabel.Text = 'Views/Cam';
            app.ViewsCamSpinner = uispinner(app.UIFigure);
            app.ViewsCamSpinner.Limits = [1 Inf];
            app.ViewsCamSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewsCamSpinnerValueChanged, true);
            app.ViewsCamSpinner.Value = 1;
            app.CurrentPointCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPointCoordinatesEditFieldLabel.Text = 'Current Point Coordinates';
            app.CurrentPointCoordinatesEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentPointCoordinatesEditField.Editable = 'off';
            app.CurrentPointCoordinatesEditField.Value = 'X = , Y = , Z = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_Point_Selection_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end