classdef Camera_Calibration_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NotificationsTextArea        matlab.ui.control.TextArea
        NotificationsTextAreaLabel   matlab.ui.control.Label
        CameraSelectorDropDown       matlab.ui.control.DropDown
        CameraSelectorDropDownLabel  matlab.ui.control.Label
        Exposurex02msSlider          matlab.ui.control.Slider
        Exposurex02msSliderLabel     matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        SaveDataButton               matlab.ui.control.Button
        CaptureFramesPanel           matlab.ui.container.Panel
        SnapBackgroundButton         matlab.ui.control.Button
        PointsNeededTextArea         matlab.ui.control.TextArea
        PointsNeededTextAreaLabel    matlab.ui.control.Label
        SnapImageButton              matlab.ui.control.Button
        GoBackButton                 matlab.ui.control.Button
        GotonextButton               matlab.ui.control.Button
        CurrentPointCoordinatesEditField  matlab.ui.control.EditField
        MovetonextpointLabel         matlab.ui.control.Label
        CurrentPointLabel            matlab.ui.control.Label
        XYZSetupPanel                matlab.ui.container.Panel
        ZmaxEditField                matlab.ui.control.NumericEditField
        ZmaxEditField_2Label         matlab.ui.control.Label
        ZminEditField                matlab.ui.control.NumericEditField
        ZminEditField_2Label         matlab.ui.control.Label
        YmaxEditField                matlab.ui.control.NumericEditField
        YmaxEditField_2Label         matlab.ui.control.Label
        YminEditField                matlab.ui.control.NumericEditField
        YminEditField_2Label         matlab.ui.control.Label
        XmaxEditField                matlab.ui.control.NumericEditField
        XmaxEditField_2Label         matlab.ui.control.Label
        XminEditField                matlab.ui.control.NumericEditField
        XminEditField_2Label         matlab.ui.control.Label
        InitializeButton             matlab.ui.control.Button
        PointsDimSlider              matlab.ui.control.Slider
        PointsDimSliderLabel         matlab.ui.control.Label
        StopPreviewButton            matlab.ui.control.Button
        ComputeDLTButton             matlab.ui.control.Button
        PreviewButton                matlab.ui.control.Button
        UIAxes_XYZ                   matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load([pwd '\config.mat'],'cameras');
    
            % --- Hardcoded working camera configuration ---
            cameras.adaptor  = {'winvideo', 'winvideo'};
            cameras.deviceID = [1, 2];                      % plain numeric array
            cameras.format   = {'BYBG_800x600', 'BYBG_800x600'};
            cameras.rROI     = {[], []};
            save([pwd '\config.mat'],'-append','cameras');
            % ----------------------------------------------
        
            app.CameraSelectorDropDown.Items = cellfun(@(x,y,z)[x '_' num2str(y) '_' z],...
                cameras.adaptor, num2cell(cameras.deviceID), cameras.format, 'UniformOutput', false);
        
            nVideo = numel(cameras.deviceID);
            vid  = cell(nVideo,1);
            src  = cell(nVideo,1);
            for i = 1:nVideo
                vid{i} = videoinput(cameras.adaptor{i}, cameras.deviceID(i), cameras.format{i});
                if ~isempty(cameras.rROI{i})
                    vid{i}.ROIPosition = cameras.rROI{i};
                end
                src{i} = getselectedsource(vid{i});
                % src{i}.ExposureTime = app.Exposurex02msSlider.Value * 200;
            end
        
            imageAxes = {app.UIAxes, app.UIAxes_2, app.UIAxes_3};
            if ~evalin('base','exist("vid","var") == 1')
                assignin('base','vid',       vid);
                assignin('base','src',       src);
                assignin('base','nVideo',    nVideo);
                assignin('base','imageAxes', imageAxes);
                assignin('base','frame',     cell(nVideo,1));
                assignin('base','n',         1);
            end
            if ~evalin('base','exist("filepath","var") == 1')
                assignin('base','filepath',[]);
            end
            app.NotificationsTextArea.Value = 'Startup complete.';

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            vid = evalin('base', 'vid');
            nVideo = evalin('base', 'nVideo');
            imageAxes = evalin('base', 'imageAxes');
        
            % Start continuous acquisition on all cameras
            for i = 1:nVideo
                vid{i}.FramesPerTrigger = Inf;
                triggerconfig(vid{i}, 'immediate');
                start(vid{i});
            end
            % for i = 1:nVideo
            %     flushdata(vid{i});  % clears old buffered frames
            % end
            assignin('base', 'vid', vid);
        
            t = timer('ExecutionMode', 'fixedRate', 'Period', .5, ...
                'TimerFcn', @(~,~) localPreview());
            start(t);
            assignin('base', 'previewTimer', t);    
            function localPreview()
                if ~isvalid(app)
                    return;
                end
            
                for i = 1:nVideo
                    try
                        % Grab only the latest frame (no buffering buildup)
                        f = getsnapshot(vid{i});
            
                        % Display it
                        imagesc(f, 'Parent', imageAxes{i});
                        colormap(imageAxes{i}, 'gray');
                        for i = 1:nVideo
                            flushdata(vid{i});  % clears old buffered frames
                        end
                    catch
                    end
                end
            end
        end

        % Button pushed function: StopPreviewButton
        function StopPreviewButtonPushed(app, event)
            try
                t = evalin('base', 'previewTimer');
                stop(t);
                delete(t);
            catch
            end
            try
                vid = evalin('base', 'vid');
                nVideo = evalin('base', 'nVideo');
                for i = 1:nVideo
                    stop(vid{i});
                end
            catch
            end
        end

        % Button pushed function: SnapImageButton
        function SnapImageButtonPushed(app, event)
            frame = evalin('base', 'frame');
            vid = evalin('base', 'vid');
            n = evalin('base', 'n');
            pointsNeeded = evalin('base', 'pointsNeeded');
            nVideo = evalin('base', 'nVideo');
            imageAxes = evalin('base', 'imageAxes');
            for i = 1:nVideo
                snap = getsnapshot(vid{i});
                % If snapshot is RGB, take first channel (Bayer raw is identical across channels)
                if size(snap, 3) > 1
                    snap = snap(:,:,1);
                end
                frame{i}(:,:,n) = snap;
                imagesc(snap, 'Parent', imageAxes{i});
                colormap(imageAxes{i}, 'gray');
            end
            pointsNeeded = setdiff(pointsNeeded, n);
            app.PointsNeededTextArea.Value = {num2str(pointsNeeded)};
        
            assignin('base', 'frame', frame);
            assignin('base', 'pointsNeeded', pointsNeeded);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            XX = round(linspace(app.XminEditField.Value,app.XmaxEditField.Value,round(app.PointsDimSlider.Value)));
            YY = round(linspace(app.YminEditField.Value,app.YmaxEditField.Value,round(app.PointsDimSlider.Value)));
            ZZ = round(linspace(app.ZminEditField.Value,app.ZmaxEditField.Value,round(app.PointsDimSlider.Value)));
            
            [X,Y,Z] = meshgrid(XX,YY,ZZ);
            points.XX = reshape(X,[],1);
            points.YY = reshape(Y,[],1);
            points.ZZ = reshape(Z,[],1);
            pointsNeeded = 1:numel(points.XX);

            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(1),points.YY(1),points.ZZ(1));
            app.CurrentPointLabel.Text = sprintf('Current Point: %d/%d', 1, numel(points.XX));
            app.PointsNeededTextArea.Value = {num2str(pointsNeeded)};

            scatter3(app.UIAxes_XYZ,points.XX,points.YY,points.ZZ);
            hold(app.UIAxes_XYZ,'on');
            scatter3(app.UIAxes_XYZ,points.XX(1),points.YY(1),points.ZZ(1),'r*');
            hold(app.UIAxes_XYZ,'off');

            vid = evalin( 'base', 'vid' );
            nVideo = evalin( 'base', 'nVideo' );
            frame = cell(nVideo,1);
            for i = 1:nVideo
                vidRes = vid{i}.VideoResolution; 
                %vidRes = vid{i}.ROIPosition; 
                frame{i} = zeros(vidRes(2), vidRes(1), numel(points.XX), 'uint8');
            end

            assignin('base','points',points);
            assignin('base','n',1);
            assignin('base','pointsNeeded',pointsNeeded);
            assignin('base','frame',frame);
        end

        % Button pushed function: GotonextButton
        function GotonextButtonPushed(app, event)
            n = evalin( 'base', 'n' );
            points = evalin('base', 'points');
            n = min(n+1,numel(points.XX));
            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(n),points.YY(n),points.ZZ(n));
            app.CurrentPointLabel.Text = sprintf('Current Point: %d/%d', n, numel(points.XX));
            scatter3(app.UIAxes_XYZ,points.XX,points.YY,points.ZZ);
            hold(app.UIAxes_XYZ,'on');
            scatter3(app.UIAxes_XYZ,points.XX(n),points.YY(n),points.ZZ(n),'r*');
            hold(app.UIAxes_XYZ,'off');
            assignin('base','n',n);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            n = evalin( 'base', 'n' );
            n = max(n-1,1);
            points = evalin('base', 'points');
            app.CurrentPointCoordinatesEditField.Value = sprintf('X = %d, Y = %d, Z = %d \n',points.XX(n),points.YY(n),points.ZZ(n));
            app.CurrentPointLabel.Text = sprintf('Current Point: %d/%d', n, numel(points.XX));
            scatter3(app.UIAxes_XYZ,points.XX,points.YY,points.ZZ);
            hold(app.UIAxes_XYZ,'on');
            scatter3(app.UIAxes_XYZ,points.XX(n),points.YY(n),points.ZZ(n),'r*');
            hold(app.UIAxes_XYZ,'off');
            assignin('base','n',n);
        end

        % Button pushed function: SnapBackgroundButton
        function SnapBackgroundButtonPushed(app, event)
            background = evalin( 'base', 'frame' );
            vid = evalin( 'base', 'vid' );
            nVideo = evalin( 'base', 'nVideo' );
            imageAxes = evalin( 'base', 'imageAxes' );
            for i = 1:nVideo
                background{i} = getsnapshot(vid{i});
                imagesc(background{i}, 'Parent', imageAxes{i});
            end

            assignin('base','background',background);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            frame = evalin('base','frame');
            background = evalin('base','background');
            points = evalin('base', 'points');
            filepath = evalin('base','filepath');
            [filename,filepath] = uiputfile([filepath 'DLT_Calibration.mat'],'Name Save File');
            assignin('base','filepath',filepath);
            save([filepath filename],'frame','background','points');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            filepath = evalin('base','filepath');
            [filename,filepath]=uigetfile({[filepath '*.mat']},'Select Calibration File');
            load([filepath filename],'frame','background','points');

            assignin('base','frame',frame);
            assignin('base','background',background);
            assignin('base','points',points);
            assignin('base','filepath',filepath);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             try
%                 vid = evalin( 'base', 'vid' );
%                 nVideo = evalin( 'base', 'nVideo' );
%                 for i = 1:nVideo
%                     delete(vid{i});
%                 end
%                 assignin('base','vid',vid);
%             catch
%                 disp('no video object to delete')
%             end
%             delete(app)
            try
                t = evalin('base', 'previewTimer');
                stop(t);
                delete(t);
            catch
            end
            imaqreset
            evalin('base','clear');
            delete(app)
        end

        % Button pushed function: ComputeDLTButton
        function ComputeDLTButtonPushed(app, event)
            Calibration_Point_Selection_GUI();
        end

        % Value changing function: Exposurex02msSlider
        function Exposurex02msSliderValueChanging(app, event)
            %changingValue = event.Value;
            src = evalin( 'base', 'src' );
            currVid = strcmpi(app.CameraSelectorDropDown.Value,app.CameraSelectorDropDown.Items);
            src{currVid}.ExposureTime = ceil(event.Value*200);
            assignin('base','src',src);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Camera 1')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabelRotationMode = 'manual';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabelRotationMode = 'manual';
            app.UIAxes.ZTickLabelRotationMode = 'manual';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Camera 2')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabelRotationMode = 'manual';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabelRotationMode = 'manual';
            app.UIAxes_2.ZTickLabelRotationMode = 'manual';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Camera 3')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabelRotationMode = 'manual';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabelRotationMode = 'manual';
            app.UIAxes_3.ZTickLabelRotationMode = 'manual';
            app.UIAxes_XYZ = uiaxes(app.UIFigure);
            title(app.UIAxes_XYZ, 'Real Coordinates')
            app.UIAxes_XYZ.XTick = [];
            app.UIAxes_XYZ.XTickLabelRotationMode = 'manual';
            app.UIAxes_XYZ.YTick = [];
            app.UIAxes_XYZ.YTickLabelRotationMode = 'manual';
            app.UIAxes_XYZ.ZTickLabelRotationMode = 'manual';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ComputeDLTButton = uibutton(app.UIFigure, 'push');
            app.ComputeDLTButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDLTButtonPushed, true);
            app.ComputeDLTButton.Text = 'Compute DLT';
            app.StopPreviewButton = uibutton(app.UIFigure, 'push');
            app.StopPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StopPreviewButtonPushed, true);
            app.StopPreviewButton.Text = 'Stop Preview';
            app.XYZSetupPanel = uipanel(app.UIFigure);
            app.XYZSetupPanel.Title = 'XYZ Setup';
            app.PointsDimSliderLabel = uilabel(app.XYZSetupPanel);
            app.PointsDimSliderLabel.Text = {'Points/'; 'Dim'};
            app.PointsDimSlider = uislider(app.XYZSetupPanel);
            app.PointsDimSlider.Limits = [1 10];
            app.PointsDimSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PointsDimSlider.Value = 4;
            app.InitializeButton = uibutton(app.XYZSetupPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.XminEditField_2Label = uilabel(app.XYZSetupPanel);
            app.XminEditField_2Label.Text = 'Xmin';
            app.XminEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.XminEditField.ValueDisplayFormat = '%.0f';
            app.XmaxEditField_2Label = uilabel(app.XYZSetupPanel);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.XmaxEditField.ValueDisplayFormat = '%.0f';
            app.XmaxEditField.Value = 2500;
            app.YminEditField_2Label = uilabel(app.XYZSetupPanel);
            app.YminEditField_2Label.Text = 'Ymin';
            app.YminEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.YminEditField.ValueDisplayFormat = '%.0f';
            app.YmaxEditField_2Label = uilabel(app.XYZSetupPanel);
            app.YmaxEditField_2Label.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.YmaxEditField.ValueDisplayFormat = '%.0f';
            app.YmaxEditField.Value = 2500;
            app.ZminEditField_2Label = uilabel(app.XYZSetupPanel);
            app.ZminEditField_2Label.Text = 'Zmin';
            app.ZminEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.ZminEditField.ValueDisplayFormat = '%.0f';
            app.ZmaxEditField_2Label = uilabel(app.XYZSetupPanel);
            app.ZmaxEditField_2Label.Text = 'Zmax';
            app.ZmaxEditField = uieditfield(app.XYZSetupPanel, 'numeric');
            app.ZmaxEditField.ValueDisplayFormat = '%.0f';
            app.ZmaxEditField.Value = 2500;
            app.CaptureFramesPanel = uipanel(app.UIFigure);
            app.CaptureFramesPanel.Title = 'Capture Frames';
            app.CurrentPointLabel = uilabel(app.CaptureFramesPanel);
            app.CurrentPointLabel.Text = 'Current Point: 1/n';
            app.MovetonextpointLabel = uilabel(app.CaptureFramesPanel);
            app.MovetonextpointLabel.Text = 'Current Point Coordinates';
            app.CurrentPointCoordinatesEditField = uieditfield(app.CaptureFramesPanel, 'text');
            app.CurrentPointCoordinatesEditField.Editable = 'off';
            app.CurrentPointCoordinatesEditField.Value = 'X = , Y = , Z = ';
            app.GotonextButton = uibutton(app.CaptureFramesPanel, 'push');
            app.GotonextButton.ButtonPushedFcn = createCallbackFcn(app, @GotonextButtonPushed, true);
            app.GotonextButton.Text = 'Go to next';
            app.GoBackButton = uibutton(app.CaptureFramesPanel, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.SnapImageButton = uibutton(app.CaptureFramesPanel, 'push');
            app.SnapImageButton.ButtonPushedFcn = createCallbackFcn(app, @SnapImageButtonPushed, true);
            app.SnapImageButton.Text = 'Snap Image';
            app.PointsNeededTextAreaLabel = uilabel(app.CaptureFramesPanel);
            app.PointsNeededTextAreaLabel.Text = 'Points Needed';
            app.PointsNeededTextArea = uitextarea(app.CaptureFramesPanel);
            app.PointsNeededTextArea.Editable = 'off';
            app.PointsNeededTextArea.Value = {'1,2'};
            app.SnapBackgroundButton = uibutton(app.CaptureFramesPanel, 'push');
            app.SnapBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SnapBackgroundButtonPushed, true);
            app.SnapBackgroundButton.Text = 'Snap Background';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Exposurex02msSliderLabel = uilabel(app.UIFigure);
            app.Exposurex02msSliderLabel.Text = 'Exposure (x0.2 ms)';
            app.Exposurex02msSlider = uislider(app.UIFigure);
            app.Exposurex02msSlider.ValueChangingFcn = createCallbackFcn(app, @Exposurex02msSliderValueChanging, true);
            app.Exposurex02msSlider.MajorTicksMode = 'manual';
            app.Exposurex02msSlider.Value = 50;
            app.CameraSelectorDropDownLabel = uilabel(app.UIFigure);
            app.CameraSelectorDropDownLabel.Text = 'Camera Selector';
            app.CameraSelectorDropDown = uidropdown(app.UIFigure);
            app.CameraSelectorDropDown.Items = {'1', '2'};
            app.CameraSelectorDropDown.Value = '1';
            app.NotificationsTextAreaLabel = uilabel(app.UIFigure);
            app.NotificationsTextAreaLabel.Text = 'Notifications';
            app.NotificationsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camera_Calibration_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end